classdef aerodaq_mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AeroDAQUIFigure                 matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        DataCollectionTab               matlab.ui.container.Tab
        CollectioninprogressLamp        matlab.ui.control.Lamp
        CollectioninprogressLampLabel   matlab.ui.control.Label
        ProgramStatusTextArea           matlab.ui.control.TextArea
        ProgramStatusTextAreaLabel      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DigitalOut1Tab                  matlab.ui.container.Tab
        StepPositionEditField           matlab.ui.control.NumericEditField
        StepPositionEditField_2Label    matlab.ui.control.Label
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        NegButton                       matlab.ui.control.RadioButton
        PosButton                       matlab.ui.control.RadioButton
        GotoZeroButton                  matlab.ui.control.Button
        SetZeroButton                   matlab.ui.control.Button
        NoStepsEditField                matlab.ui.control.NumericEditField
        NoStepsEditFieldLabel           matlab.ui.control.Label
        EnabledigitaloutCheckBox        matlab.ui.control.CheckBox
        DigitalOut2Tab                  matlab.ui.container.Tab
        StepPositionEditField_2         matlab.ui.control.NumericEditField
        StepPositionEditFieldLabel      matlab.ui.control.Label
        NoStepsEditField_2              matlab.ui.control.NumericEditField
        NoStepsEditField_2Label         matlab.ui.control.Label
        DirectionButtonGroup_2          matlab.ui.container.ButtonGroup
        NegButton_2                     matlab.ui.control.RadioButton
        PosButton_2                     matlab.ui.control.RadioButton
        GotoZeroButton_2                matlab.ui.control.Button
        SetZeroButton_2                 matlab.ui.control.Button
        EnabledigitaloutCheckBox_2      matlab.ui.control.CheckBox
        AnalogOutTab                    matlab.ui.container.Tab
        StepPositionEditField_3         matlab.ui.control.NumericEditField
        StepPositionEditField_3Label    matlab.ui.control.Label
        NoStepsEditField_3              matlab.ui.control.NumericEditField
        NoStepsEditField_3Label         matlab.ui.control.Label
        DirectionButtonGroup_3          matlab.ui.container.ButtonGroup
        NegButton_3                     matlab.ui.control.RadioButton
        PosButton_3                     matlab.ui.control.RadioButton
        GotoZeroButton_3                matlab.ui.control.Button
        SetZeroButton_3                 matlab.ui.control.Button
        EnabledigitaloutCheckBox_3      matlab.ui.control.CheckBox
        DataHistoryPanel                matlab.ui.container.Panel
        SaveButton_2                    matlab.ui.control.Button
        DeleteAllButton_2               matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        DropDown_2                      matlab.ui.control.DropDown
        DataPanel                       matlab.ui.container.Panel
        AutosaveDataCheckBox            matlab.ui.control.CheckBox
        DeleteAllButton                 matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        ExecutePanel                    matlab.ui.container.Panel
        PauseStopButton                 matlab.ui.control.Button
        TimeoutsEditField               matlab.ui.control.NumericEditField
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        RepetitionsEditField            matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel       matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        AnalogInPanel                   matlab.ui.container.Panel
        EnableanaloginCheckBox          matlab.ui.control.CheckBox
        ChannelsEditField               matlab.ui.control.EditField
        ChannelsEditFieldLabel          matlab.ui.control.Label
        SampleDurationsEditField        matlab.ui.control.NumericEditField
        SampleDurationsEditFieldLabel   matlab.ui.control.Label
        NoSamplesEditField              matlab.ui.control.NumericEditField
        NoSamplesEditFieldLabel         matlab.ui.control.Label
        SamplingHzEditField             matlab.ui.control.NumericEditField
        SamplingHzEditFieldLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        AnalogOutPanel                  matlab.ui.container.Panel
        PulseVoltageVEditField          matlab.ui.control.NumericEditField
        PulseVoltageVEditFieldLabel     matlab.ui.control.Label
        PulseWidthusEditField_4         matlab.ui.control.NumericEditField
        PulseWidthusEditField_4Label    matlab.ui.control.Label
        DutyproportionEditField_4       matlab.ui.control.NumericEditField
        DutyproportionEditField_4Label  matlab.ui.control.Label
        CycleFrequencyHzEditField_4     matlab.ui.control.NumericEditField
        CycleFrequencyHzEditField_4Label  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StepperMotorDriverSwitchSettingsLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Setup11252023TextArea           matlab.ui.control.TextArea
        Setup11252023TextAreaLabel      matlab.ui.control.Label
        SelectDAQBox                    matlab.ui.control.ListBox
        SelectDAQListBoxLabel           matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        DigitalOut2Panel                matlab.ui.container.Panel
        PulseWidthusEditField_2         matlab.ui.control.NumericEditField
        PulseWidthusEditField_2Label    matlab.ui.control.Label
        EnableChannelEditField_2        matlab.ui.control.NumericEditField
        EnableChannelEditField_2Label   matlab.ui.control.Label
        StepChannelEditField_2          matlab.ui.control.NumericEditField
        StepChannelEditField_2Label     matlab.ui.control.Label
        DirectionChannelEditField_2     matlab.ui.control.NumericEditField
        DirectionChannelEditField_2Label  matlab.ui.control.Label
        DutyproportionEditField_2       matlab.ui.control.NumericEditField
        DutyproportionEditField_2Label  matlab.ui.control.Label
        CycleFrequencyHzEditField_2     matlab.ui.control.NumericEditField
        CycleFrequencyHzEditFieldLabel  matlab.ui.control.Label
        ScanivalvePanel                 matlab.ui.container.Panel
        PulseWidthusEditField_3         matlab.ui.control.NumericEditField
        PulseWidthusEditField_3Label    matlab.ui.control.Label
        DutyproportionEditField_3       matlab.ui.control.NumericEditField
        DutyproportionEditFieldLabel    matlab.ui.control.Label
        CycleFrequencyHzEditField_3     matlab.ui.control.NumericEditField
        CycleFrequencyHzEditField_2Label  matlab.ui.control.Label
        HomeChannelEditField            matlab.ui.control.NumericEditField
        HomeChannelEditFieldLabel       matlab.ui.control.Label
        DigitalOut1Panel                matlab.ui.container.Panel
        PulseWidthusEditField           matlab.ui.control.NumericEditField
        PulseWidthusEditFieldLabel      matlab.ui.control.Label
        EnableChannelEditField          matlab.ui.control.NumericEditField
        EnableChannelEditFieldLabel     matlab.ui.control.Label
        StepChannelEditField            matlab.ui.control.NumericEditField
        StepChannelEditFieldLabel       matlab.ui.control.Label
        DirectionChannelEditField       matlab.ui.control.NumericEditField
        DirectionChannelEditFieldLabel  matlab.ui.control.Label
        DutyproportionEditField         matlab.ui.control.NumericEditField
        DutyproportionEditField_3Label  matlab.ui.control.Label
        CycleFrequencyHzEditField       matlab.ui.control.NumericEditField
        CycleFrequencyHzEditField_3Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        cnt % Description
        acq % Timeseries data
        dio %Digital out settings
        stop % Start stop flag
    end
    
    methods (Access = private)
        
        function plotData(app)
            % Data
            if strcmp(app.DropDown.Value,'Time-series')
                x = 1:size(app.acq.timeseries.data,1);x=x/app.SamplingHzEditField.Value;
                plot(app.UIAxes,x,app.acq.timeseries.data, ".-")
                app.UIAxes.XScale = 'linear';app.UIAxes.YScale = 'linear';
                app.UIAxes.XLabel.String='time [s]';
                legend(app.UIAxes)
                app.UIAxes.Title.String = ['[updated ',datestr(clock,13),']'];
            elseif strcmp(app.DropDown.Value,'Time-series mean removed')
                x = 1:size(app.acq.timeseries.data,1);x=x/app.SamplingHzEditField.Value;
                plot(app.UIAxes,x,app.acq.timeseries.data-app.acq.mean(end,:), ".-")
                app.UIAxes.XScale = 'linear';app.UIAxes.YScale = 'linear';
                app.UIAxes.XLabel.String='time [s]';
                legend(app.UIAxes)
                app.UIAxes.Title.String = ['[updated ',datestr(clock,13),']'];
            elseif strcmp(app.DropDown.Value,'FFT')
                [PSD,f] = BlockPSD(app);
                d = find(f<app.SamplingHzEditField.Value/2);
                plot(app.UIAxes,f(d,:),PSD(d,:), ".-")
                app.UIAxes.XScale = 'linear';app.UIAxes.YScale = 'linear';
                app.UIAxes.XLabel.String='freq [Hz]';
                legend(app.UIAxes)
            elseif strcmp(app.DropDown.Value,'FFT loglog')
                [PSD,f] = BlockPSD(app);
                d = find(f<app.SamplingHzEditField.Value/2);
                plot(app.UIAxes,f(d,:),PSD(d,:), ".-")
                app.UIAxes.XScale = 'log';app.UIAxes.YScale = 'log';
                app.UIAxes.XLabel.String='freq [Hz]';
                legend(app.UIAxes)
            end
        end
        %--------------------------------------------------------------
        function plotDataHistory(app)
            % Data History            
            x = 1:size(app.acq.mean,1);
            if strcmp(app.DropDown_2.Value,'Mean')
                plot(app.UIAxes2,x,app.acq.mean,'-o')
            elseif strcmp(app.DropDown_2.Value,'Standard Deviation')
                plot(app.UIAxes2,x,app.acq.std,'-o')
            elseif strcmp(app.DropDown_2.Value,'Mean with STD Bars')
                for i = 1:size(app.acq.mean,2)
                    errorbar(app.UIAxes2,x,app.acq.mean(:,i),app.acq.std(:,i),'-o')
                    hold(app.UIAxes2, 'on')
                end
                hold(app.UIAxes2, 'off')
            elseif strcmp(app.DropDown_2.Value,'Mean minus first point')
                plot(app.UIAxes2,x,app.acq.mean-app.acq.mean(1,:),'-o')
            end
            legend(app.UIAxes2)
            app.UIAxes2.Title.String = ['[updated ',datestr(clock,13),']'];
        end
        %--------------------------------------------------------------
        function [PSD,f] = BlockPSD(app)
            % Compute PSD
            data = app.acq.timeseries.data;
            fs = app.SamplingHzEditField.Value;
            varargin{1}=1;
            
            if ndims(data)>3;error('Can''t input matrix with dim>3');end
            Nblock=100;
            dim=find(size(data)~=1,1);
            if nargin>2&&~isempty(varargin{1})
                Nblock=varargin{1};
            end
            if nargin>3
                dim=varargin{2};
            end
            
            permutation=[dim,2,3];permutation(dim)=1;
            data=permute(data,permutation); %convert data so vector is dim 1
            
            data=data(1:end-mod(size(data,1),Nblock),:,:); %shorten data so N/Nblock is int
            N=size(data,1);
            NN=N/Nblock;
            f=fs*(0:NN-1)'/NN;
            win=0.5*(1-cos(2*pi*(1:NN)/NN)); %Hanning Window
            win2=meshgrid(win,1:Nblock)';
            % x1=reshape(data,NN,Nblock).*win2;
            % ftv1=sqrt(8/3)*fft(x1)/NN;
            % PSD=mean(abs(ftv1).^2/(fs/NN),2);
            
            x1=reshape(data,NN,Nblock,size(data,2),[]).*win2;
            ftv1=sqrt(8/3)*fft(x1,[],1)/NN;
            PSD=squeeze(mean(abs(ftv1).^2/(fs/NN),2));
            PSD=permute(PSD,permutation); %convert data back to original form
        end
        
        %--------------------------------------------------------------
        function sdisp(app,s)
            app.ProgramStatusTextArea.Value{end+1} = ['[',datestr(clock,13),'] ',...
                s];
        end
        
        %--------------------------------------------------------------
        function refreshDaqlist(app)
            temp = daqlist;
            if isempty(temp)
                app.SelectDAQBox.Items = {'No DAQ Found'};
            else
                app.SelectDAQBox.Items = append(temp.VendorID,' | ',temp.DeviceID);
            end
        end
        
        %--------------------------------------------------------------
        function flag_stop = autosaveTimeseries(app)
            %save time series data
            %fn  = sprintf('timeseries%04i.mat',ai);
            fn  = sprintf('%s%04i.mat',app.acq.timeseries.prefix,app.cnt);
            
            %check for existing files
            olddir  = pwd;
            cd(app.acq.timeseries.path);
            bull  = exist(fn,'file');
            cd(olddir);
            flag_stop=0;
            if bull && ~app.acq.timeseries.flag_yestoall
                b  = questdlg(['The file ' fn ' already exists. Do you want to overwrite?'], 'Warning', ...
                    'Yes and don''t ask again','Yes','Stop','Stop');
                if isequal(b,'Yes and don''t ask again')
                    app.acq.timeseries.flag_yestoall  = 1;
                elseif isequal(b,'Stop')
                    flag_stop  = 1;
                    sdisp(app,'Data autosave canceled. %s already exists',fn)
                    return;
                end
            end
            %write data to file
            if ~bull || isequal(b,'Yes') || app.acq.timeseries.flag_yestoall
                fs = app.SamplingHzEditField.Value;
                channels = eval(['[',app.ChannelsEditField.Value,']']);
                npts = app.NoSamplesEditField.Value;
                timeseries_data = app.acq.timeseries.data;    
                save([app.acq.timeseries.path filesep fn],'timeseries_data','fs','channels','npts');
                sdisp(app,['Saved times-series data to ' fn '.']);
            else
                sdisp(app,['Skipped saving time-series file ' fn '.']);
            end
        end
        
        %--------------------------------------------------------------
        function do_move(app,do,Nsteps,direction)
            %sampling frequency
            fs  = app.dio.fs(do);
            
            %duty cycle
            duty  = app.dio.duty(do);
            
            %acquire digital subsystem            
            temp = string(app.SelectDAQBox.Value);
            temp = reshape(split(temp,' | '),size(temp,2),2);
            Input_Cards = temp(:,2);
                
            % Convert channel number to Data Translation naming
            %%%%%% NOTE Data Translation pin 20-26 --> do port 0-6 %%%%%%
            device = daq('dt');
            channelDir = sprintf('port0/line%d',app.dio.channelDir(do));
            channelStep = sprintf('port0/line%d',app.dio.channelStep(do));
            channelEnable = sprintf('port0/line%d',app.dio.channelEnable(do));
            % Add output channels
            addoutput(device,Input_Cards(1),channelDir,'Digital');
            addoutput(device,Input_Cards(1),channelStep,'Digital');
            addoutput(device,Input_Cards(1),channelEnable,'Digital');
                         
            
            %set direction to value from gui
            command(1) = direction;
            %set step to low
            command(2) = 0;
            %set enable to low
            command(3) = 0;
            %send command to stepper motor
            write(device, command);
            
            %generate pulse train
            timeout  = 1/fs*(1-duty);
            for ai  = 1:Nsteps
                tic
                write(device, [direction,0,0]);
                while toc<timeout;end
                write(device, [direction,1,0]);
                while toc<1/fs;end
            end
            
            %set direction to low, step to low, enable to high
            write(device,[0,0,1]);
            
        end
        
        %--------------------------------------------------------------
        function Zdo_gohome(app)  %return the scannivalve
            %sampling frequency
            fs  = app.dio.fs_scan;
            
            %duty cycle
            duty  = app.dio.duty_scan;
            
            %acquire digital subsystem            
            temp = string(app.SelectDAQBox.Value);
            temp = reshape(split(temp,' | '),size(temp,2),2);
            Input_Cards = temp(:,2);

            device = daq('dt');
            channelScanivalve = sprintf('port0/line%d',app.dio.channelScanivalve);
            addoutput(device,Input_Cards(1),channelScanivalve,'Digital');
            
            %output a single pulse 
            write(device, 0);
            pause(1/fs*duty)
            write(device, 1);
            pause(1/fs*duty)
            write(device, 0);
            
        end

        function ao_move(app,do,Nsteps,direction)
            % Analog output
            % ON BOARD: OUTPUT DAC Ch0 -> Constant Current Direction
            %           OUTOUT DAC Ch1 -> Step Pulses
            
            % Wave Generation Frequency
            fs  = app.dio.fs(do);
            
            %duty cycle
            duty  = app.dio.duty(do);

            %pulse voltage
            v = app.dio.pulseVolt(do);
            
            %acquire digital subsystem            
            temp = string(app.SelectDAQBox.Value);
            temp = reshape(split(temp,' | '),size(temp,2),2);
            Input_Cards = temp(:,2);

            device = daq('dt');
            addoutput(device,Input_Cards(1),0,'Voltage');
            addoutput(device,Input_Cards(1),1,'Voltage');
            device.Rate = fs;

            %set direction to value from gui
            command(1) = direction*v;
            %set step to low
            command(2) = 0;
            %send command to stepper motor
            write(device, command);
            
            %generate pulse train
            timeout  = 1/fs*(1-duty);
            for ai  = 1:Nsteps
                tic
                write(device, v*[direction,0]);
                while toc<timeout;end
                write(device, v*[direction,1]);
                while toc<1/fs;end
            end
            
            %set direction to low, step to low, enable to high
            write(device,5*[0,0]);
        end
        
        function stopApp(app)
            app.stop = false;
            sdisp(app,'Process stopped.')
            set(app.DeleteAllButton,'Enable','on')
            set(app.DeleteButton,'Enable','on')
            set(app.SaveButton,'Enable','on')
            set(app.DeleteAllButton_2,'Enable','on')
            set(app.SaveButton_2,'Enable','on')
            set(app.RunButton,'Enable','on')
            set(app.PauseStopButton,'Enable','off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %add required paths
            addpath('C:\Users\Aerolab\Desktop\Tim Stuff\AeroDAQ App')
            
            %initialize variables
            app.cnt=1;
            app.stop = false;
            app.acq.timeseries.flag_yestoall=0;
            app.acq.timeseries.path  = [];
            app.acq.timeseries.defaultpath  = pwd;
            app.acq.timeseries.data  = [];
            app.acq.mean  = [];
            app.acq.std   = [];
            
            app.dio.position(1) = 0;
            app.dio.position(2) = 0;
            app.dio.position(3) = 0;
%             app.dio.fs(1)    = 200;  %[Hz] 200=force balance, 500=linear traverse
%             app.dio.duty(1)  = 0.0015; 
%             app.dio.fs(2)    = 200;  %[Hz] 200=force balance, 500=linear traverse
%             app.dio.duty(2)  = 0.0015; 
%             app.dio.channelDir(1) = 0;
%             app.dio.channelStep(1) = 6; %Formerly 1, bad ch
%             app.dio.channelEnable(1) = 2; % Enable is grounded
%             app.dio.channelDir(2) = 0;
%             app.dio.channelStep(2) = 5; %Formerly 1, bad ch
%             app.dio.channelEnable(2) = 2; % Enable is grounded
%             app.dio.channelScanivalve = 3; 
%             app.dio.fs_scan = 200;
%             app.dio.duty_scan = 0.0015;
            if ~isfield(app.dio,'fs');SaveSettingsButtonPushed(app);end
            
            refreshDaqlist(app)
            app.ProgramStatusTextArea.Value = ['[',datestr(clock,13),']',...
                ' GUI created successfully'];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             % Make Pause/Stop button change instead of having popout window
%             if strcmp(app.RunButton.Text,'Resume')
%                 uiresume(app.AeroDAQUIFigure)
%                 app.RunButton.Text = 'Run';
%                 app.RunButton.Enable = false;
%                 app.PauseStopButton.Text = 'Pause';
%                 return
%             end
            app.RunButton.Enable = false;
            app.PauseStopButton.Enable = true;
            
            temp = string(app.SelectDAQBox.Value);
            temp = reshape(split(temp,' | '),size(temp,2),2);
            Input_Cards = temp(:,2);
            cardType = temp(:,1);
            channels = eval(['{',app.ChannelsEditField.Value,'}']);
%             Input_Channels = [uint16(length(channels))];
            Input_Channels = uint16(cell2mat(channels));
            Sample_Rate = app.SamplingHzEditField.Value;
            Sample_Duration = app.NoSamplesEditField.Value/Sample_Rate;
            nchan = length(channels);
            N = app.RepetitionsEditField.Value;
            Nstep(1) = app.NoStepsEditField.Value;
            Nstep(2) = app.NoStepsEditField_2.Value;
            Nstep(3) = app.NoStepsEditField_3.Value;
            Dir(1) = double(app.PosButton.Value);
            Dir(2) = double(app.PosButton_2.Value);
            Dir(3) = double(app.PosButton_3.Value);
            flag_ai = app.EnableanaloginCheckBox.Value;
            flag_do(1) = app.EnabledigitaloutCheckBox.Value;
            flag_do(2) = app.EnabledigitaloutCheckBox_2.Value;
            flag_do(3) = app.EnabledigitaloutCheckBox_3.Value;
            timeout = app.TimeoutsEditField.Value;
            SaveSettingsButtonPushed(app)

            % Setup DAQ
            if flag_ai
                warning('off','nidaq:ni:propUpdatedOnAllChannels');
%                 devices = daqlist;
                % Check what kind of DAQ it is
                isNI = strcmp(cardType,'ni');
                isDT = strcmp(cardType,'dt');
                if any(isNI)&&any(isDT)
                    errordlg('Code cannot read Data Translation and National Instruments DAQ simultaneously.')
                    stopApp(app)
                    return
                elseif any(isDT)
                    device = daq('dt');
                elseif any(isNI)
                    device = daq('ni');
                else
                    errordlg(sprintf('Code cannot read DAQ of the type "%s."',cardType(~isDT&~isNI)))
                    stopApp(app)
                    return
                end

                for aa = 1:length(Input_Cards)
    %                 addinput(device,Input_Cards{aa},0:Input_Channels(aa)-1,'Bridge');
                    if contains(Input_Cards(aa),'cDAQ',IgnoreCase=true)||contains(Input_Cards(aa),'DT',IgnoreCase=true)
                        addinput(device,Input_Cards{aa},Input_Channels,'Voltage');
                    elseif contains(Input_Cards(aa),'PXI',IgnoreCase=true)
                        addinput(device,Input_Cards(aa),Input_Channels,'Bridge');
                        for bb = (aa-1)*length(Input_Channels)+(1:length(Input_Channels))
                            % Kulites
                            device.Channels(1,bb).BridgeMode = 'Full';
                            device.Channels(1,bb).NominalBridgeResistance = 1000;
                            device.Channels(1,bb).ExcitationVoltage = 10;
                        end
                    end
                end
                device.Rate = Sample_Rate;
            end
            
            % Begin execution
            sdisp(app,'Starting process.');
            for ai = 1:N
                %check for stop
                if app.stop
                    stopApp(app)
                    return
                end
                
                % Collect analog in data
                if flag_ai
                    % check for channel seize versus mean data
                    [r,c]  = size(app.acq.mean);
                    if ~isequal(nchan,c) && r && c
                        sdisp(app,sprintf('Data error, nchan = %i, h.acq.mean size = %ix%i.',nchan,r,c));
                        errordlg('Number of channels is not equal to that found in plot data.  Adjust the number of channels or clear Plot 2 data to continue.', 'Error', 'modal');
                        break;
                    end
                    sdisp(app,sprintf('Starting Data Collection %d',app.cnt));
                    app.CollectioninprogressLamp.Color = [0,1,0];
                    
                   % Acquire data
                    app.acq.timeseries.data = read(device,seconds(Sample_Duration));
                    if app.stop
                        stopApp(app)
                        return
                    end
                    app.CollectioninprogressLamp.Color = [0.90,0.90,0.90];
                    app.acq.timeseries.data = table2array(app.acq.timeseries.data);
            
                    app.acq.mean(app.cnt,:)=mean(app.acq.timeseries.data);
                    app.acq.std(app.cnt,:)=std(app.acq.timeseries.data);
                    
                    if app.AutosaveDataCheckBox.Value
                        flag_stop = autosaveTimeseries(app);
                        if flag_stop; sdisp(app,'Data autosave canceled. ');end
                    end
                    
                    % Plot
                    plotData(app)
                    plotDataHistory(app)
                    app.cnt=app.cnt+1;
                end
                
                %check for stop
                if app.stop
                    stopApp(app)
                    return
                end
                
                % move Digital out
                for do = 1:3
                    if flag_do(do)
                        %skip the last step if ai is turned on.
                        if (isequal(ai,N) && flag_ai)
                           	sdisp(app,'Skipping last motor movement.');
                           	sdisp(app,'Skipping last timeout.');
                        else
                            %status update
                            sdisp(app,sprintf('Moving DO_%d %i steps in the %i direction.',do,Nstep(do),Dir(do)));
                            
                            %check that DAQ is dt
                            if ~strcmp(cardType(1),'dt')
                                errordlg(sprintf('Code cannot digital output to DAQ of type "%s."',cardType(1)))
                                stopApp(app)
                                return
                            end
                            %move nsteps
                            if do==1||do==2
                                do_move(app,do,Nstep(do),Dir(do));
                            else
                                ao_move(app,do,Nstep(do),Dir(do))
                            end
                            %change position
                            app.dio.position(do)  = app.dio.position(do) + Nstep(do)*(2*Dir(do)-1);
            
%                             %enable/disable motor buttons as needed
%                             if isequal(h.position,0)
%                                 %set(h.do.pushbutton.return,'Enable','off');
%                                 %set(h.do.pushbutton.zero,'Enable','off');
%                             else
%                                 %set(h.do.pushbutton.return,'Enable','on');
%                                 %set(h.do.pushbutton.zero,'Enable','on');
%                             end
            
            
                            %status update
                            sdisp(app,sprintf('Current DO_%d position at %d.',do,app.dio.position(do)));
                            
                            if app.stop
                                stopApp(app)
                                return
                            end

            				%do timeout
            				sdisp(app,sprintf('Timeout for %2.2f seconds.',timeout));
            				pause(timeout);
                            
                        end
                    end
                end 
                %set position indicator
                app.StepPositionEditField.Value =  app.dio.position(1);
                app.StepPositionEditField_2.Value =  app.dio.position(2);
                app.StepPositionEditField_3.Value =  app.dio.position(3);
            end

            
            set(app.DeleteAllButton,'Enable','on')
            set(app.DeleteButton,'Enable','on')
            set(app.SaveButton,'Enable','on')
            set(app.DeleteAllButton_2,'Enable','on')
            set(app.SaveButton_2,'Enable','on')
            set(app.RunButton,'Enable','on')
            set(app.PauseStopButton,'Enable','off')
            sdisp(app,'Process complete.')
        end

        % Button pushed function: PauseStopButton
        function PauseStopButtonPushed(app, event)
%             % Make Pause/Stop button change instead of having popout window
%             if strcmp(app.PauseStopButton.Text,'Pause')
%                 app.PauseStopButton.Text = 'Stop';
%                 app.RunButton.Text = 'Resume';
%                 set(app.RunButton,'Enable','on')
%                 uiwait(app.AeroDAQUIFigure)
%             elseif strcmp(app.PauseStopButton.Text,'Stop')
%                 uiresume(app.AeroDAQUIFigure)
%                 app.PauseStopButton.Text = 'Pause';
%                 set(app.RunButton,'Enable','off')
%                 app.RunButton.Text = 'Run';
%                 app.stop=true;
%             end

            res=questdlg('Data acquisition paused','Run Paused','Resume','Stop','Resume');
            if strcmp(res,'Resume')
            elseif strcmp(res,'Stop')
                app.stop=true;
            end    
        end

        % Value changed function: NoSamplesEditField
        function NoSamplesEditFieldValueChanged(app, event)
            value = app.NoSamplesEditField.Value;
            app.SampleDurationsEditField.Value = value/app.SamplingHzEditField.Value;
        end

        % Value changed function: SampleDurationsEditField
        function SampleDurationsEditFieldValueChanged(app, event)
            value = app.SampleDurationsEditField.Value;
            app.NoSamplesEditField.Value = value*app.SamplingHzEditField.Value;
        end

        % Value changed function: DropDown
        function DropDown_2ValueChanged(app, event)
            plotData(app)
        end

        % Value changed function: DropDown_2
        function DropDownValueChanged(app, event)
            plotDataHistory(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            %make cell array of data for list dialog
            for ai  = 1:size(app.acq.mean,1)
            	listcell{ai}  = sprintf('index=%d, mean=%02.3f, std=%02.3f',ai,app.acq.mean(ai),app.acq.std(ai));
            end
            
            %get screensize for list dialog
            ss  = get(0,'ScreenSize');
            listx  = round(ss(3)*.25);
            listy  = round(ss(4)*.5);
            
            %make list dialog box
            sdisp(app,'Prompting user to select data for removal.');
            selection  = listdlg('liststring',listcell,'selectionmode','multiple','name','Delete', ...
            	'PromptString','Select data to remove:','okstring','Delete','listsize',[listx,listy]);
            if ~isempty(selection)
            	sdisp(app,sprintf('%i data points selected for removal.',length(selection)));
            	%prompt user again to make sure
                choice2  = questdlg('Are you REALLY sure?  The data will be lost FOREVER.','Warning','Yes','No','No');
                if isequal(choice2,'Yes')
            		if isequal(length(selection),length(app.acq.mean))
            			%clear all data
            			app.acq.mean  = [];
            	 		app.acq.std   = []; 	
                        app.cnt = 1;
            			sdisp(app,sprintf('Deleted all %i data points.',length(selection)));
            		else
            	    	%clear the selected data
            	        app.acq.mean(selection,:)  = [];
            	        app.acq.std(selection,:)   = [];
                        app.cnt = max(app.cnt-length(selection),1);
            	        sdisp(app,sprintf('Deleted %i data points.',length(selection)));			
            		end
                    plotDataHistory(app)
                    
            	else
            		sdisp(app,'Data removal cancelled.');
                end
            else
            	sdisp(app,'Data removal cancelled.');
            end
        end

        % Button pushed function: DeleteAllButton_2
        function DeleteAllButton_2Pushed(app, event)
            choice1 = questdlg('Are you sure you want to clear all stored mean and standard deviation data?','Warning','Yes','No','No');
            if strcmp(choice1,'Yes')
               choice2 = questdlg('Are you sure REALLY sure? The data will be lost FOREVER','Warning','Yes','No','No');
               if strcmp(choice2,'Yes')
                   app.acq.mean=[];
                   app.acq.std=[];
                   app.cnt=1;
                   plot(app.UIAxes2,0,nan, ".-")
                   sdisp(app,'Data History cleared')
                
                    set(app.DeleteAllButton_2,'Enable','off')
                    set(app.DeleteButton,'Enable','off')
                    set(app.SaveButton_2,'Enable','off')
               end
            end
       
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            % Prompt user for fie
            [file,path] = uiputfile(...
                {'*.mat','MAT-files (*.mat)'},...
                'Select a file location');
            % check that a file and directory were selected
            if ~sum(file) || ~sum(path)
                return
            end
            
            fs = app.SamplingHzEditField.Value;
            channels = eval(['[',app.ChannelsEditField.Value,']']);
            npts = app.NoSamplesEditField.Value;
            mean_data = app.acq.mean;
            std_data = app.acq.std;
            
            save([path,file],'fs','channels','npts','mean_data','std_data')
            sdisp(app,['Data History saved to',file]);
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            choice1 = questdlg('Are you sure you want to clear the last run''s timeseries data?','Warning','Yes','No','No');
            if strcmp(choice1,'Yes')
                app.acq.timeseries.data=[];
                app.cnt=max(app.cnt-1,1);
                plot(app.UIAxes,0,nan, ".-")
                sdisp(app,'Data Timeseries cleared');
                set(app.DeleteAllButton,'Enable','off')
                set(app.SaveButton,'Enable','off')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Prompt user for fie
            [file,path] = uiputfile(...
                {'*.mat','MAT-files (*.mat)'},...
                'Select a file location');
            % check that a file and directory were selected
            if ~sum(file) || ~sum(path)
                return
            end
            
            fs = app.SamplingHzEditField.Value;
            channels = eval(['[',app.ChannelsEditField.Value,']']);
            npts = app.NoSamplesEditField.Value;
            timeseries_data = app.acq.timeseries.data;
            
            save([path,file],'fs','channels','npts','timeseries_data')
            sdisp(app,['Data History saved to ',file]);
        end

        % Value changed function: SamplingHzEditField
        function SamplingHzEditFieldValueChanged(app, event)
            fs = app.SamplingHzEditField.Value;
            app.NoSamplesEditField.Value = fs*app.SampleDurationsEditField.Value;
        end

        % Value changed function: AutosaveDataCheckBox
        function AutosaveDataCheckBoxValueChanged(app, event)
            if app.AutosaveDataCheckBox.Value
                %prompt user for folder to store data
                app.acq.timeseries.path  = uigetdir(app.acq.timeseries.defaultpath,'Folder for time-series files');
            
                %check that a selection was made
                if ~app.acq.timeseries.path
                    app.acq.timeseries.path  = [];
                    app.AutosaveDataCheckBox.Value=0;
                else
                    %prompt user for prefix
                    app.acq.timeseries.prefix  = char(inputdlg('File prefix:'));
                    
                    %check input
                    if isempty(app.acq.timeseries.prefix)
                        app.acq.timeseries.prefix  = 'timeseries';
                    else
                        app.acq.timeseries.prefix  = app.acq.timeseries.prefix;
                    end
                    
                    %status update
                    sdisp(app,'Time-series autosave enabled.');
                    sdisp(app,sprintf('Prefix set to %s.',app.acq.timeseries.prefix));
                    ii  = find(app.acq.timeseries.path==filesep,1,'last');
                    sdisp(app,['Autosave path: ' app.acq.timeseries.path(ii+1:end) '.']);
                end
            else
                app.acq.timeseries.path  = [];
                sdisp(app,'Time-series autosave disabled.');
            end
        end

        % Value changed function: EnableanaloginCheckBox
        function EnableanaloginCheckBoxValueChanged(app, event)
            value = app.EnableanaloginCheckBox.Value;
            if value
                set(app.ChannelsEditField,'Enable','on');
                set(app.SampleDurationsEditField,'Enable','on');
                set(app.NoSamplesEditField,'Enable','on');
                set(app.SamplingHzEditField,'Enable','on');
                sdisp(app,'Analog in is enabled.');
            else
                set(app.ChannelsEditField,'Enable','off');
                set(app.SampleDurationsEditField,'Enable','off');
                set(app.NoSamplesEditField,'Enable','off');
                set(app.SamplingHzEditField,'Enable','off');
                sdisp(app,'Analog in is disabled.');
            end            
        end

        % Value changed function: EnabledigitaloutCheckBox
        function EnabledigitaloutCheckBoxValueChanged(app, event)
            value = app.EnabledigitaloutCheckBox.Value;
            if value
                set(app.StepPositionEditField,'Enable','on')
                set(app.GotoZeroButton,'Enable','on')
                set(app.SetZeroButton,'Enable','on')
                set(app.NoStepsEditField,'Enable','on')
                set(app.PosButton,'Enable','on')
                set(app.NegButton,'Enable','on')
                sdisp(app,'Digital out 1 is enabled.');
                if app.EnabledigitaloutCheckBox_2.Value
                    set(app.EnabledigitaloutCheckBox_2,'Value',false)
                    sdisp(app,'Digital out 2 is disabled');
                end
                if app.EnabledigitaloutCheckBox_3.Value
                    set(app.EnabledigitaloutCheckBox_3,'Value',false)
                    sdisp(app,'Analog out is disabled');
                end
            else
                set(app.StepPositionEditField,'Enable','off')
                set(app.GotoZeroButton,'Enable','off')
                set(app.SetZeroButton,'Enable','off')
                set(app.NoStepsEditField,'Enable','off')
                set(app.PosButton,'Enable','off')
                set(app.NegButton,'Enable','off')
                sdisp(app,'Digital out 1 is disabled.');
            end
        end

        % Value changed function: EnabledigitaloutCheckBox_2
        function EnabledigitaloutCheckBox_2ValueChanged(app, event)
            value = app.EnabledigitaloutCheckBox_2.Value;
            if value
                set(app.StepPositionEditField_2,'Enable','on')
                set(app.GotoZeroButton_2,'Enable','on')
                set(app.SetZeroButton_2,'Enable','on')
                set(app.NoStepsEditField_2,'Enable','on')
                set(app.PosButton_2,'Enable','on')
                set(app.NegButton_2,'Enable','on')
                sdisp(app,'Digital out 2 is enabled.');
                if app.EnabledigitaloutCheckBox.Value
                    set(app.EnabledigitaloutCheckBox,'Value',false)
                    sdisp(app,'Digital out 1 is disabled');
                end
                if app.EnabledigitaloutCheckBox_3.Value
                    set(app.EnabledigitaloutCheckBox_3,'Value',false)
                    sdisp(app,'Analog out is disabled');
                end
            else
                set(app.StepPositionEditField_2,'Enable','off')
                set(app.GotoZeroButton_2,'Enable','off')
                set(app.SetZeroButton_2,'Enable','off')
                set(app.NoStepsEditField_2,'Enable','off')
                set(app.PosButton_2,'Enable','off')
                set(app.NegButton_2,'Enable','off')
                sdisp(app,'Digital out 2 is disabled.');
            end
        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            %prompt user to make sure
            choice  = questdlg('Are you sure you want to set the Digital Out 1 position to zero?','Warning','Yes','No','No');
            
            %check user input
            if isequal(choice,'Yes')
                %set X position to zero
                app.dio.position(1)  = 0;
                
                %set position indicator
                app.StepPositionEditField.Value =  app.dio.position(1);
            
                
                %status update
                sdisp(app,'Digital Out 1 position reset to 0.');
            end
        end

        % Button pushed function: SetZeroButton_2
        function SetZeroButton_2Pushed(app, event)
            %prompt user to make sure
            choice  = questdlg('Are you sure you want to set the Digital Out 2 position to zero?','Warning','Yes','No','No');
            
            %check user input
            if isequal(choice,'Yes')
                %set X position to zero
                app.dio.position(2)  = 0;
                
                %set position indicator
                app.StepPositionEditField_2.Value =  app.dio.position(2);
            
                
                %status update
                sdisp(app,'Digital Out 2 position reset to 0.');
            end
        end

        % Button pushed function: GotoZeroButton
        function GotoZeroButtonPushed(app, event)
            %prompt user to make sure
            choice  = questdlg('Are you sure you want to return Digital Out 1 to the zero position?  This will also send the Scanivalve home.','Warning','Yes','No','No');
            
            if isequal(choice,'Yes')
                %get direction
                if app.dio.position(1) > 0
                    direction  = 0;
                else
                    direction  = 1;
                end
                
                %update status
                sdisp(app,sprintf('Moving Digital Out 1 %i steps in the %i direction.',abs(app.dio.position(1)),direction));
                
                %move motor to zero point
                do_move(app,1,abs(app.dio.position(1)),direction);
                %move_stepper(abs(h.position),direction);
                
                %set position to zero
                app.dio.position(1)  = 0;
                
                %set position indicator
                app.StepPositionEditField.Value =  app.dio.position(1);
                                
                %return scannivalve
                sdisp(app,'Sending return pulse to Scannivalve.');   
                Zdo_gohome(app);
            
                %status update
                sdisp(app,'Position returned to 0.');
                
                sdisp(app,'Done.');
            end
        end

        % Button pushed function: GotoZeroButton_2
        function GotoZeroButton_2Pushed(app, event)
            %prompt user to make sure
            choice  = questdlg('Are you sure you want to return Digital Out 2 to the zero position?  This will also send the Scanivalve home.','Warning','Yes','No','No');
            
            if isequal(choice,'Yes')
                %get direction
                if app.dio.position(2) > 0
                    direction  = 0;
                else
                    direction  = 1;
                end
                
                %update status
                sdisp(app,sprintf('Moving Digital Out 2 %i steps in the %i direction.',abs(app.dio.position(2)),direction));
                
                %move motor to zero point
                do_move(app,2,abs(app.dio.position(2)),direction);
                %move_stepper(abs(h.position),direction);
                
                %set position to zero
                app.dio.position(1)  = 0;
                
                %set position indicator
                app.StepPositionEditField_2.Value =  app.dio.position(2);
                                
                %return scannivalve
                sdisp(app,'Sending return pulse to Scannivalve.');   
                Zdo_gohome(app);
            
                %status update
                sdisp(app,'Position returned to 0.');
                
                sdisp(app,'Done.');
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Only saves settings if the Save Settings button is pressed
            app.dio.fs(1)    = app.CycleFrequencyHzEditField.Value; 
            app.dio.duty(1)  = app.DutyproportionEditField_3.Value; 
            app.dio.fs(2)    = app.CycleFrequencyHzEditField_2.Value;  %[Hz] 200=force balance, 500=linear traverse
            app.dio.duty(2)  = app.DutyproportionEditField_2.Value; 
            app.dio.channelDir(1) = app.DirectionChannelEditField.Value;
            app.dio.channelStep(1) = app.StepChannelEditField.Value; 
            app.dio.channelEnable(1) = app.EnableChannelEditField.Value; % Enable is grounded
            app.dio.channelDir(2) = app.DirectionChannelEditField_2.Value;
            app.dio.channelStep(2) = app.StepChannelEditField_2.Value; 
            app.dio.channelEnable(2) = app.EnableChannelEditField_2.Value; % Enable is grounded
            app.dio.channelScanivalve = app.HomeChannelEditField.Value; 
            app.dio.fs_scan = app.CycleFrequencyHzEditField.Value;
            app.dio.duty_scan = app.DutyproportionEditField.Value; 
            app.dio.fs(3)    = app.CycleFrequencyHzEditField_4.Value;  %[Hz] 200=force balance, 500=linear traverse
            app.dio.duty(3)  = app.DutyproportionEditField_4.Value;
            app.dio.pulseVolt(3) = app.PulseVoltageVEditField.Value;
        end

        % Button down function: SettingsTab
        function SettingsTabButtonDown(app, event)
            % Resets values to the last saved values
            app.CycleFrequencyHzEditField.Value = app.dio.fs(1); 
            app.DutyproportionEditField.Value = app.dio.duty(1);
            app.PulseWidthusEditField.Value = 1/app.dio.fs(1)*app.dio.duty(1)*1e6;
            app.CycleFrequencyHzEditField_2.Value = app.dio.fs(2);
            app.DutyproportionEditField_2.Value = app.dio.duty(2); 
            app.PulseWidthusEditField_2.Value = 1/app.dio.fs(2)*app.dio.duty(2)*1e6;
            app.DirectionChannelEditField.Value = app.dio.channelDir(1);
            app.StepChannelEditField.Value = app.dio.channelStep(1); 
            app.EnableChannelEditField.Value = app.dio.channelEnable(1); % Enable is grounded
            app.DirectionChannelEditField_2.Value = app.dio.channelDir(2);
            app.StepChannelEditField_2.Value = app.dio.channelStep(2); 
            app.EnableChannelEditField_2.Value = app.dio.channelEnable(2); % Enable is grounded
            app.HomeChannelEditField.Value = app.dio.channelScanivalve; 
            app.CycleFrequencyHzEditField_3.Value = app.dio.fs_scan;
            app.DutyproportionEditField_3.Value = app.dio.duty_scan; 
            app.CycleFrequencyHzEditField_4.Value = app.dio.fs(3);
            app.DutyproportionEditField_4.Value = app.dio.duty(3); 
            app.PulseWidthusEditField_4.Value = 1/app.dio.fs(3)*app.dio.duty(3)*1e6;
            app.PulseVoltageVEditField.Value = app.dio.pulseVolt(3);
        end

        % Value changed function: DutyproportionEditField
        function DutyproportionEditFieldValueChanged(app, event)
            value = app.DutyproportionEditField.Value;
            app.PulseWidthusEditField.Value = 1/app.CycleFrequencyHzEditField.Value*value*1e6;
        end

        % Value changed function: DutyproportionEditField_2
        function DutyproportionEditField_2ValueChanged(app, event)
            value = app.DutyproportionEditField_2.Value;
            app.PulseWidthusEditField_2.Value = 1/app.CycleFrequencyHzEditField_2.Value*value*1e6;
        end

        % Value changed function: DutyproportionEditField_3
        function DutyproportionEditField_3ValueChanged(app, event)
            value = app.DutyproportionEditField_3.Value;
            app.PulseWidthusEditField_3.Value = 1/app.CycleFrequencyHzEditField_3.Value*value*1e6;
        end

        % Value changed function: PulseWidthusEditField
        function PulseWidthusEditFieldValueChanged(app, event)
            value = app.PulseWidthusEditField.Value;
            app.DutyproportionEditField.Value = value*1e-6*app.CycleFrequencyHzEditField.Value;
        end

        % Value changed function: PulseWidthusEditField_2
        function PulseWidthusEditField_2ValueChanged(app, event)
            value = app.PulseWidthusEditField_2.Value;
            app.DutyproportionEditField_2.Value = value*1e-6*app.CycleFrequencyHzEditField_2.Value;
        end

        % Value changed function: PulseWidthusEditField_3
        function PulseWidthusEditField_3ValueChanged(app, event)
            value = app.PulseWidthusEditField_3.Value;
            app.DutyproportionEditField_3.Value = value*1e-6*app.CycleFrequencyHzEditField_3.Value;
        end

        % Value changed function: CycleFrequencyHzEditField
        function CycleFrequencyHzEditFieldValueChanged(app, event)
            value = app.CycleFrequencyHzEditField.Value;
            app.DutyproportionEditField.Value = app.PulseWidthusEditField.Value*1e-6*value;
        end

        % Value changed function: CycleFrequencyHzEditField_2
        function CycleFrequencyHzEditField_2ValueChanged(app, event)
            value = app.CycleFrequencyHzEditField_2.Value;
            app.DutyproportionEditField_2.Value = app.PulseWidthusEditField_2.Value*1e-6*value;
        end

        % Value changed function: CycleFrequencyHzEditField_3
        function CycleFrequencyHzEditField_3ValueChanged(app, event)
            value = app.CycleFrequencyHzEditField_3.Value;
            app.DutyproportionEditField_2.Value = app.PulseWidthusEditField_2.Value*1e-6*value;
        end

        % Value changed function: EnabledigitaloutCheckBox_3
        function EnabledigitaloutCheckBox_3ValueChanged(app, event)
            value = app.EnabledigitaloutCheckBox_3.Value;
            if value
                set(app.StepPositionEditField_3,'Enable','on')
                set(app.GotoZeroButton_3,'Enable','on')
                set(app.SetZeroButton_3,'Enable','on')
                set(app.NoStepsEditField_3,'Enable','on')
                set(app.PosButton_3,'Enable','on')
                set(app.NegButton_3,'Enable','on')
                sdisp(app,'Analog out is enabled.');
                if app.EnabledigitaloutCheckBox.Value
                    set(app.EnabledigitaloutCheckBox,'Value',false)
                    sdisp(app,'Digital out 1 is disabled');
                end
                if app.EnabledigitaloutCheckBox_2.Value
                    set(app.EnabledigitaloutCheckBox_2,'Value',false)
                    sdisp(app,'Digital out 2 is disabled');
                end
            else
                set(app.StepPositionEditField_3,'Enable','off')
                set(app.GotoZeroButton_3,'Enable','off')
                set(app.SetZeroButton_3,'Enable','off')
                set(app.NoStepsEditField_3,'Enable','off')
                set(app.PosButton_3,'Enable','off')
                set(app.NegButton_3,'Enable','off')
                sdisp(app,'Analog out is disabled.');
            end
        end

        % Value changed function: DutyproportionEditField_4
        function DutyproportionEditField_4ValueChanged(app, event)
            value = app.DutyproportionEditField_4.Value;
            app.PulseWidthusEditField_4.Value = 1/app.CycleFrequencyHzEditField_4.Value*value*1e6;
        end

        % Value changed function: CycleFrequencyHzEditField_4
        function CycleFrequencyHzEditField_4ValueChanged(app, event)
            value = app.CycleFrequencyHzEditField_4.Value;
            app.DutyproportionEditField_4.Value = app.PulseWidthusEditField_4.Value*1e-6*value;
        end

        % Value changed function: PulseWidthusEditField_4
        function PulseWidthusEditField_4ValueChanged(app, event)
            value = app.PulseWidthusEditField_4.Value;
            app.DutyproportionEditField_4.Value = value*1e-6*app.CycleFrequencyHzEditField_4.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AeroDAQUIFigure = uifigure('Visible', 'off');
            app.AeroDAQUIFigure.Name = 'AeroDAQ';
            app.AeroDAQUIFigure.Icon = 'Notre_Dame_logo.jpg';
            app.TabGroup2 = uitabgroup(app.AeroDAQUIFigure);
            app.DataCollectionTab = uitab(app.TabGroup2);
            app.DataCollectionTab.Title = 'Data Collection';
            app.UIAxes = uiaxes(app.DataCollectionTab);
            title(app.UIAxes, 'Title', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'voltage', 'Interpreter', 'latex')
            app.UIAxes2 = uiaxes(app.DataCollectionTab);
            title(app.UIAxes2, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'test', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'voltage', 'Interpreter', 'latex')
            app.AnalogInPanel = uipanel(app.DataCollectionTab);
            app.AnalogInPanel.Title = 'Analog In';
            app.SamplingHzEditFieldLabel = uilabel(app.AnalogInPanel);
            app.SamplingHzEditFieldLabel.Text = 'Sampling [Hz]';
            app.SamplingHzEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.SamplingHzEditField.Limits = [1e-10 Inf];
            app.SamplingHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingHzEditFieldValueChanged, true);
            app.SamplingHzEditField.Value = 10000;
            app.NoSamplesEditFieldLabel = uilabel(app.AnalogInPanel);
            app.NoSamplesEditFieldLabel.Text = 'No. Samples';
            app.NoSamplesEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.NoSamplesEditField.Limits = [1 Inf];
            app.NoSamplesEditField.RoundFractionalValues = 'on';
            app.NoSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @NoSamplesEditFieldValueChanged, true);
            app.NoSamplesEditField.Value = 100000;
            app.SampleDurationsEditFieldLabel = uilabel(app.AnalogInPanel);
            app.SampleDurationsEditFieldLabel.Text = 'Sample Duration [s]';
            app.SampleDurationsEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.SampleDurationsEditField.Limits = [0 Inf];
            app.SampleDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDurationsEditFieldValueChanged, true);
            app.SampleDurationsEditField.Value = 10;
            app.ChannelsEditFieldLabel = uilabel(app.AnalogInPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.AnalogInPanel, 'text');
            app.ChannelsEditField.Value = '00,01,02,03';
            app.EnableanaloginCheckBox = uicheckbox(app.AnalogInPanel);
            app.EnableanaloginCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableanaloginCheckBoxValueChanged, true);
            app.EnableanaloginCheckBox.Text = 'Enable analog in';
            app.EnableanaloginCheckBox.Value = true;
            app.ExecutePanel = uipanel(app.DataCollectionTab);
            app.ExecutePanel.Title = 'Execute';
            app.RunButton = uibutton(app.ExecutePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RepetitionsEditFieldLabel = uilabel(app.ExecutePanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.ExecutePanel, 'numeric');
            app.RepetitionsEditField.Limits = [1 Inf];
            app.RepetitionsEditField.Value = 1;
            app.TimeoutsEditFieldLabel = uilabel(app.ExecutePanel);
            app.TimeoutsEditFieldLabel.Text = 'Timeout [s]';
            app.TimeoutsEditField = uieditfield(app.ExecutePanel, 'numeric');
            app.PauseStopButton = uibutton(app.ExecutePanel, 'push');
            app.PauseStopButton.ButtonPushedFcn = createCallbackFcn(app, @PauseStopButtonPushed, true);
            app.PauseStopButton.Enable = 'off';
            app.PauseStopButton.Text = 'Pause/Stop';
            app.DataPanel = uipanel(app.DataCollectionTab);
            app.DataPanel.Title = 'Data';
            app.SaveButton = uibutton(app.DataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DropDown = uidropdown(app.DataPanel);
            app.DropDown.Items = {'Time-series', 'Time-series mean removed', 'FFT', 'FFT loglog'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown.Value = 'Time-series';
            app.DeleteAllButton = uibutton(app.DataPanel, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Enable = 'off';
            app.DeleteAllButton.Text = 'Delete All';
            app.AutosaveDataCheckBox = uicheckbox(app.DataPanel);
            app.AutosaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutosaveDataCheckBoxValueChanged, true);
            app.AutosaveDataCheckBox.Text = 'Autosave Data';
            app.DataHistoryPanel = uipanel(app.DataCollectionTab);
            app.DataHistoryPanel.Title = 'Data History';
            app.DropDown_2 = uidropdown(app.DataHistoryPanel);
            app.DropDown_2.Items = {'Mean', 'Standard Deviation', 'Mean with STD Bars', 'Mean minus first point'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown_2.Value = 'Mean';
            app.DeleteButton = uibutton(app.DataHistoryPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.DeleteAllButton_2 = uibutton(app.DataHistoryPanel, 'push');
            app.DeleteAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButton_2Pushed, true);
            app.DeleteAllButton_2.Enable = 'off';
            app.DeleteAllButton_2.Text = 'Delete All';
            app.SaveButton_2 = uibutton(app.DataHistoryPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.TabGroup = uitabgroup(app.DataCollectionTab);
            app.DigitalOut1Tab = uitab(app.TabGroup);
            app.DigitalOut1Tab.Title = 'Digital Out 1';
            app.EnabledigitaloutCheckBox = uicheckbox(app.DigitalOut1Tab);
            app.EnabledigitaloutCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledigitaloutCheckBoxValueChanged, true);
            app.EnabledigitaloutCheckBox.Text = 'Enable digital out 1';
            app.NoStepsEditFieldLabel = uilabel(app.DigitalOut1Tab);
            app.NoStepsEditFieldLabel.Text = 'No. Steps';
            app.NoStepsEditField = uieditfield(app.DigitalOut1Tab, 'numeric');
            app.NoStepsEditField.Limits = [0 Inf];
            app.NoStepsEditField.RoundFractionalValues = 'on';
            app.NoStepsEditField.Enable = 'off';
            app.NoStepsEditField.Value = 200;
            app.SetZeroButton = uibutton(app.DigitalOut1Tab, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Enable = 'off';
            app.SetZeroButton.Text = 'Set Zero';
            app.GotoZeroButton = uibutton(app.DigitalOut1Tab, 'push');
            app.GotoZeroButton.ButtonPushedFcn = createCallbackFcn(app, @GotoZeroButtonPushed, true);
            app.GotoZeroButton.Enable = 'off';
            app.GotoZeroButton.Text = 'Go to Zero';
            app.DirectionButtonGroup = uibuttongroup(app.DigitalOut1Tab);
            app.DirectionButtonGroup.Title = 'Direction';
            app.PosButton = uiradiobutton(app.DirectionButtonGroup);
            app.PosButton.Enable = 'off';
            app.PosButton.Text = 'Pos.';
            app.PosButton.Value = true;
            app.NegButton = uiradiobutton(app.DirectionButtonGroup);
            app.NegButton.Enable = 'off';
            app.NegButton.Text = 'Neg.';
            app.StepPositionEditField_2Label = uilabel(app.DigitalOut1Tab);
            app.StepPositionEditField_2Label.Text = 'Step Position';
            app.StepPositionEditField = uieditfield(app.DigitalOut1Tab, 'numeric');
            app.StepPositionEditField.Editable = 'off';
            app.DigitalOut2Tab = uitab(app.TabGroup);
            app.DigitalOut2Tab.Title = 'Digital Out 2';
            app.EnabledigitaloutCheckBox_2 = uicheckbox(app.DigitalOut2Tab);
            app.EnabledigitaloutCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnabledigitaloutCheckBox_2ValueChanged, true);
            app.EnabledigitaloutCheckBox_2.Text = 'Enable digital out 2';
            app.SetZeroButton_2 = uibutton(app.DigitalOut2Tab, 'push');
            app.SetZeroButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButton_2Pushed, true);
            app.SetZeroButton_2.Enable = 'off';
            app.SetZeroButton_2.Text = 'Set Zero';
            app.GotoZeroButton_2 = uibutton(app.DigitalOut2Tab, 'push');
            app.GotoZeroButton_2.ButtonPushedFcn = createCallbackFcn(app, @GotoZeroButton_2Pushed, true);
            app.GotoZeroButton_2.Enable = 'off';
            app.GotoZeroButton_2.Text = 'Go to Zero';
            app.DirectionButtonGroup_2 = uibuttongroup(app.DigitalOut2Tab);
            app.DirectionButtonGroup_2.Title = 'Direction';
            app.PosButton_2 = uiradiobutton(app.DirectionButtonGroup_2);
            app.PosButton_2.Enable = 'off';
            app.PosButton_2.Text = 'Pos.';
            app.PosButton_2.Value = true;
            app.NegButton_2 = uiradiobutton(app.DirectionButtonGroup_2);
            app.NegButton_2.Enable = 'off';
            app.NegButton_2.Text = 'Neg.';
            app.NoStepsEditField_2Label = uilabel(app.DigitalOut2Tab);
            app.NoStepsEditField_2Label.Text = 'No. Steps';
            app.NoStepsEditField_2 = uieditfield(app.DigitalOut2Tab, 'numeric');
            app.NoStepsEditField_2.Limits = [0 Inf];
            app.NoStepsEditField_2.RoundFractionalValues = 'on';
            app.NoStepsEditField_2.Enable = 'off';
            app.NoStepsEditField_2.Value = 200;
            app.StepPositionEditFieldLabel = uilabel(app.DigitalOut2Tab);
            app.StepPositionEditFieldLabel.Text = 'Step Position';
            app.StepPositionEditField_2 = uieditfield(app.DigitalOut2Tab, 'numeric');
            app.StepPositionEditField_2.Editable = 'off';
            app.AnalogOutTab = uitab(app.TabGroup);
            app.AnalogOutTab.Title = 'Analog Out';
            app.EnabledigitaloutCheckBox_3 = uicheckbox(app.AnalogOutTab);
            app.EnabledigitaloutCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @EnabledigitaloutCheckBox_3ValueChanged, true);
            app.EnabledigitaloutCheckBox_3.Text = 'Enable analog out ';
            app.SetZeroButton_3 = uibutton(app.AnalogOutTab, 'push');
            app.SetZeroButton_3.Enable = 'off';
            app.SetZeroButton_3.Text = 'Set Zero';
            app.GotoZeroButton_3 = uibutton(app.AnalogOutTab, 'push');
            app.GotoZeroButton_3.Enable = 'off';
            app.GotoZeroButton_3.Text = 'Go to Zero';
            app.DirectionButtonGroup_3 = uibuttongroup(app.AnalogOutTab);
            app.DirectionButtonGroup_3.Title = 'Direction';
            app.PosButton_3 = uiradiobutton(app.DirectionButtonGroup_3);
            app.PosButton_3.Enable = 'off';
            app.PosButton_3.Text = 'Pos.';
            app.PosButton_3.Value = true;
            app.NegButton_3 = uiradiobutton(app.DirectionButtonGroup_3);
            app.NegButton_3.Enable = 'off';
            app.NegButton_3.Text = 'Neg.';
            app.NoStepsEditField_3Label = uilabel(app.AnalogOutTab);
            app.NoStepsEditField_3Label.Text = 'No. Steps';
            app.NoStepsEditField_3 = uieditfield(app.AnalogOutTab, 'numeric');
            app.NoStepsEditField_3.Limits = [0 Inf];
            app.NoStepsEditField_3.RoundFractionalValues = 'on';
            app.NoStepsEditField_3.Enable = 'off';
            app.NoStepsEditField_3.Value = 200;
            app.StepPositionEditField_3Label = uilabel(app.AnalogOutTab);
            app.StepPositionEditField_3Label.Text = 'Step Position';
            app.StepPositionEditField_3 = uieditfield(app.AnalogOutTab, 'numeric');
            app.StepPositionEditField_3.Editable = 'off';
            app.ProgramStatusTextAreaLabel = uilabel(app.DataCollectionTab);
            app.ProgramStatusTextAreaLabel.Text = 'Program Status';
            app.ProgramStatusTextArea = uitextarea(app.DataCollectionTab);
            app.ProgramStatusTextArea.Editable = 'off';
            app.CollectioninprogressLampLabel = uilabel(app.DataCollectionTab);
            app.CollectioninprogressLampLabel.Text = 'Collection in progress';
            app.CollectioninprogressLamp = uilamp(app.DataCollectionTab);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTab.ButtonDownFcn = createCallbackFcn(app, @SettingsTabButtonDown, true);
            app.DigitalOut1Panel = uipanel(app.SettingsTab);
            app.DigitalOut1Panel.Title = 'Digital Out 1';
            app.CycleFrequencyHzEditField_3Label = uilabel(app.DigitalOut1Panel);
            app.CycleFrequencyHzEditField_3Label.Text = 'Cycle Frequency [Hz]';
            app.CycleFrequencyHzEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.CycleFrequencyHzEditField.Limits = [1e-10 Inf];
            app.CycleFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CycleFrequencyHzEditFieldValueChanged, true);
            app.CycleFrequencyHzEditField.Value = 200;
            app.DutyproportionEditField_3Label = uilabel(app.DigitalOut1Panel);
            app.DutyproportionEditField_3Label.Text = 'Duty proportion';
            app.DutyproportionEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.DutyproportionEditField.Limits = [0 1];
            app.DutyproportionEditField.ValueChangedFcn = createCallbackFcn(app, @DutyproportionEditFieldValueChanged, true);
            app.DutyproportionEditField.Tooltip = {'Proportion of cycle that step signal is sent to motor'};
            app.DutyproportionEditField.Value = 0.0015;
            app.DirectionChannelEditFieldLabel = uilabel(app.DigitalOut1Panel);
            app.DirectionChannelEditFieldLabel.Text = 'Direction Channel';
            app.DirectionChannelEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.StepChannelEditFieldLabel = uilabel(app.DigitalOut1Panel);
            app.StepChannelEditFieldLabel.Text = 'Step Channel';
            app.StepChannelEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.StepChannelEditField.Value = 5;
            app.EnableChannelEditFieldLabel = uilabel(app.DigitalOut1Panel);
            app.EnableChannelEditFieldLabel.Text = 'Enable Channel';
            app.EnableChannelEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.EnableChannelEditField.Value = 1;
            app.PulseWidthusEditFieldLabel = uilabel(app.DigitalOut1Panel);
            app.PulseWidthusEditFieldLabel.Text = 'Pulse Width [us]';
            app.PulseWidthusEditField = uieditfield(app.DigitalOut1Panel, 'numeric');
            app.PulseWidthusEditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidthusEditFieldValueChanged, true);
            app.PulseWidthusEditField.Value = 7.5;
            app.ScanivalvePanel = uipanel(app.SettingsTab);
            app.ScanivalvePanel.Title = 'Scanivalve';
            app.HomeChannelEditFieldLabel = uilabel(app.ScanivalvePanel);
            app.HomeChannelEditFieldLabel.Text = 'Home Channel';
            app.HomeChannelEditField = uieditfield(app.ScanivalvePanel, 'numeric');
            app.HomeChannelEditField.Value = 3;
            app.CycleFrequencyHzEditField_2Label = uilabel(app.ScanivalvePanel);
            app.CycleFrequencyHzEditField_2Label.Text = 'Cycle Frequency [Hz]';
            app.CycleFrequencyHzEditField_3 = uieditfield(app.ScanivalvePanel, 'numeric');
            app.CycleFrequencyHzEditField_3.Limits = [1e-10 Inf];
            app.CycleFrequencyHzEditField_3.ValueChangedFcn = createCallbackFcn(app, @CycleFrequencyHzEditField_3ValueChanged, true);
            app.CycleFrequencyHzEditField_3.Value = 200;
            app.DutyproportionEditFieldLabel = uilabel(app.ScanivalvePanel);
            app.DutyproportionEditFieldLabel.Text = 'Duty proportion';
            app.DutyproportionEditField_3 = uieditfield(app.ScanivalvePanel, 'numeric');
            app.DutyproportionEditField_3.Limits = [0 1];
            app.DutyproportionEditField_3.ValueChangedFcn = createCallbackFcn(app, @DutyproportionEditField_3ValueChanged, true);
            app.DutyproportionEditField_3.Tooltip = {'Proportion of cycle that step signal is sent to motor'};
            app.DutyproportionEditField_3.Value = 0.0015;
            app.PulseWidthusEditField_3Label = uilabel(app.ScanivalvePanel);
            app.PulseWidthusEditField_3Label.Text = 'Pulse Width [us]';
            app.PulseWidthusEditField_3 = uieditfield(app.ScanivalvePanel, 'numeric');
            app.PulseWidthusEditField_3.ValueChangedFcn = createCallbackFcn(app, @PulseWidthusEditField_3ValueChanged, true);
            app.PulseWidthusEditField_3.Value = 7.5;
            app.DigitalOut2Panel = uipanel(app.SettingsTab);
            app.DigitalOut2Panel.Title = 'Digital Out 2';
            app.CycleFrequencyHzEditFieldLabel = uilabel(app.DigitalOut2Panel);
            app.CycleFrequencyHzEditFieldLabel.Text = 'Cycle Frequency [Hz]';
            app.CycleFrequencyHzEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.CycleFrequencyHzEditField_2.Limits = [1e-10 Inf];
            app.CycleFrequencyHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @CycleFrequencyHzEditField_2ValueChanged, true);
            app.CycleFrequencyHzEditField_2.Value = 200;
            app.DutyproportionEditField_2Label = uilabel(app.DigitalOut2Panel);
            app.DutyproportionEditField_2Label.Text = 'Duty proportion';
            app.DutyproportionEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.DutyproportionEditField_2.Limits = [0 1];
            app.DutyproportionEditField_2.ValueChangedFcn = createCallbackFcn(app, @DutyproportionEditField_2ValueChanged, true);
            app.DutyproportionEditField_2.Tooltip = {'Proportion of cycle that step signal is sent to motor'};
            app.DutyproportionEditField_2.Value = 0.0015;
            app.DirectionChannelEditField_2Label = uilabel(app.DigitalOut2Panel);
            app.DirectionChannelEditField_2Label.Text = 'Direction Channel';
            app.DirectionChannelEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.DirectionChannelEditField_2.Value = 6;
            app.StepChannelEditField_2Label = uilabel(app.DigitalOut2Panel);
            app.StepChannelEditField_2Label.Text = 'Step Channel';
            app.StepChannelEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.StepChannelEditField_2.Value = 3;
            app.EnableChannelEditField_2Label = uilabel(app.DigitalOut2Panel);
            app.EnableChannelEditField_2Label.Text = 'Enable Channel';
            app.EnableChannelEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.EnableChannelEditField_2.Value = 2;
            app.PulseWidthusEditField_2Label = uilabel(app.DigitalOut2Panel);
            app.PulseWidthusEditField_2Label.Text = 'Pulse Width [us]';
            app.PulseWidthusEditField_2 = uieditfield(app.DigitalOut2Panel, 'numeric');
            app.PulseWidthusEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulseWidthusEditField_2ValueChanged, true);
            app.PulseWidthusEditField_2.Value = 7.5;
            app.SaveSettingsButton = uibutton(app.SettingsTab, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.SelectDAQListBoxLabel = uilabel(app.SettingsTab);
            app.SelectDAQListBoxLabel.Tooltip = {'May need to restart MATLAB to see DAQ. It must be plugged in before opening MATLAB.'};
            app.SelectDAQListBoxLabel.Text = 'Select DAQ';
            app.SelectDAQBox = uilistbox(app.SettingsTab);
            app.SelectDAQBox.Items = {'No DAQ Found'};
            app.SelectDAQBox.Multiselect = 'on';
            app.SelectDAQBox.Tooltip = {'Hold Shift to select multiple DAQs.'};
            app.SelectDAQBox.Value = {'No DAQ Found'};
            app.Setup11252023TextAreaLabel = uilabel(app.SettingsTab);
            app.Setup11252023TextAreaLabel.Text = 'Setup 11/25/2023';
            app.Setup11252023TextArea = uitextarea(app.SettingsTab);
            app.Setup11252023TextArea.Editable = 'off';
            app.Setup11252023TextArea.Value = {'DIO 0 PIN20  - Motor controller 1 direction'; 'DIO 5 PIN25  - Motor controller 1 steps or Scannivalve next'; 'DIO 6 PIN26  - Motor controller 2 direction'; 'DIO 3 PIN23  - Motor controller 2  steps or Scannivalve next'; 'DIO ? PIN?    - Scannivalve return'; 'AO 0 - Motor controller direction'; 'AO 2 - Motor controller steps'; 'GND PIN37'; ''; 'Enable is not connected to anything'};
            app.TextArea = uitextarea(app.SettingsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'  Motor      Steps/rotation  Current  | SW1 SW2 SW3 SW4 SW5 SW6'; '----------------------------------------------|----------------------------------------------'; 'NEMA 23          200              3.0     |    on    on    off     off     off     on'; 'NEMA 17          200              2.0     |    on    on    off     on     off     on'; 'NEMA 11          200             0.67    |    on    on    off     on     on    on'};
            app.StepperMotorDriverSwitchSettingsLabel = uilabel(app.SettingsTab);
            app.StepperMotorDriverSwitchSettingsLabel.Text = 'Stepper Motor Driver Switch Settings';
            app.Label = uilabel(app.SettingsTab);
            app.Label.Text = 'If motor is loud when moving, you likely have wrong current setting on driver';
            app.AnalogOutPanel = uipanel(app.SettingsTab);
            app.AnalogOutPanel.Title = 'Analog Out';
            app.CycleFrequencyHzEditField_4Label = uilabel(app.AnalogOutPanel);
            app.CycleFrequencyHzEditField_4Label.Text = 'Cycle Frequency [Hz]';
            app.CycleFrequencyHzEditField_4 = uieditfield(app.AnalogOutPanel, 'numeric');
            app.CycleFrequencyHzEditField_4.Limits = [1e-10 Inf];
            app.CycleFrequencyHzEditField_4.ValueChangedFcn = createCallbackFcn(app, @CycleFrequencyHzEditField_4ValueChanged, true);
            app.CycleFrequencyHzEditField_4.Value = 1250;
            app.DutyproportionEditField_4Label = uilabel(app.AnalogOutPanel);
            app.DutyproportionEditField_4Label.Text = 'Duty proportion';
            app.DutyproportionEditField_4 = uieditfield(app.AnalogOutPanel, 'numeric');
            app.DutyproportionEditField_4.Limits = [0 1];
            app.DutyproportionEditField_4.ValueChangedFcn = createCallbackFcn(app, @DutyproportionEditField_4ValueChanged, true);
            app.DutyproportionEditField_4.Tooltip = {'Proportion of cycle that step signal is sent to motor'};
            app.DutyproportionEditField_4.Value = 0.009375;
            app.PulseWidthusEditField_4Label = uilabel(app.AnalogOutPanel);
            app.PulseWidthusEditField_4Label.Text = 'Pulse Width [us]';
            app.PulseWidthusEditField_4 = uieditfield(app.AnalogOutPanel, 'numeric');
            app.PulseWidthusEditField_4.ValueChangedFcn = createCallbackFcn(app, @PulseWidthusEditField_4ValueChanged, true);
            app.PulseWidthusEditField_4.Value = 7.5;
            app.PulseVoltageVEditFieldLabel = uilabel(app.AnalogOutPanel);
            app.PulseVoltageVEditFieldLabel.Text = 'Pulse Voltage [V]';
            app.PulseVoltageVEditField = uieditfield(app.AnalogOutPanel, 'numeric');
            app.PulseVoltageVEditField.Limits = [0 10];
            app.PulseVoltageVEditField.Value = 5;
            % Show the figure after all components are created
            app.AeroDAQUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aerodaq_mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AeroDAQUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AeroDAQUIFigure)
        end
    end
end