classdef DataAq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ProgramStatusTextAreaLabel     matlab.ui.control.Label
        ProgramStatusTextArea          matlab.ui.control.TextArea
        AnalogInPanel                  matlab.ui.container.Panel
        SamplingHzEditFieldLabel       matlab.ui.control.Label
        SamplingHzEditField            matlab.ui.control.NumericEditField
        NoSamplesEditFieldLabel        matlab.ui.control.Label
        NoSamplesEditField             matlab.ui.control.NumericEditField
        SampleDurationsEditFieldLabel  matlab.ui.control.Label
        SampleDurationsEditField       matlab.ui.control.NumericEditField
        ChannelsEditFieldLabel         matlab.ui.control.Label
        ChannelsEditField              matlab.ui.control.EditField
        ExecutePanel                   matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        DataPanel                      matlab.ui.container.Panel
        SaveButton_2                   matlab.ui.control.Button
        DropDown_2                     matlab.ui.control.DropDown
        DeleteAllButton_2              matlab.ui.control.Button
        DataHistoryPanel               matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        DeleteButton                   matlab.ui.control.Button
        DeleteAllButton                matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        cnt % Description
        acq % Timeseries data
    end
    
    methods (Access = private)
        
        function plotData(app)
            % Data
            if strcmp(app.DropDown_2.Value,'Time-series')
                x = 1:size(app.acq.timeseries,1);x=x/app.SamplingHzEditField.Value;
                plot(app.UIAxes,x,app.acq.timeseries, ".-")
                app.UIAxes.XScale = 'linear';app.UIAxes.YScale = 'linear';
                app.UIAxes.XLabel.String='time [s]';
                legend(app.UIAxes)
                app.UIAxes.Title.String = ['[updated ',datestr(clock,13),']'];
            elseif strcmp(app.DropDown_2.Value,'FFT')
                [PSD,f] = BlockPSD(app);
                d = find(f<app.SamplingHzEditField.Value/2);
                plot(app.UIAxes,f(d,:),PSD(d,:), ".-")
                app.UIAxes.XScale = 'linear';app.UIAxes.YScale = 'linear';
                app.UIAxes.XLabel.String='freq [Hz]';
                legend(app.UIAxes)
            elseif strcmp(app.DropDown_2.Value,'FFT loglog')
                [PSD,f] = BlockPSD(app);
                d = find(f<app.SamplingHzEditField.Value/2);
                plot(app.UIAxes,f(d,:),PSD(d,:), ".-")
                app.UIAxes.XScale = 'log';app.UIAxes.YScale = 'log';
                app.UIAxes.XLabel.String='freq [Hz]';
                legend(app.UIAxes)
            end
        end
        
        function plotDataHistory(app)
            % Data History            
            x = 1:size(app.acq.mean,1);
            if strcmp(app.DropDown.Value,'Mean')
                plot(app.UIAxes2,x,app.acq.mean,'-o')
            elseif strcmp(app.DropDown.Value,'Standard Deviation')
                plot(app.UIAxes2,x,app.acq.std,'-o')
            elseif strcmp(app.DropDown.Value,'Mean with STD Bars')
                for i = 1:size(app.acq.mean,2)
                    errorbar(app.UIAxes2,x,app.acq.mean(:,i),app.acq.std(:,i),'-o')
                    hold(app.UIAxes2, 'on')
                end
                hold(app.UIAxes2, 'off')
            elseif strcmp(app.DropDown.Value,'Mean minus first point')
                plot(app.UIAxes2,x,app.acq.mean-app.acq.mean(1,:),'-o')
            end
            legend(app.UIAxes2)
            app.UIAxes2.Title.String = ['[updated ',datestr(clock,13),']'];
        end
        
        function [PSD,f] = BlockPSD(app)
            % Compute PSD
            data = app.acq.timeseries;
            fs = app.SamplingHzEditField.Value;
            varargin{1}=1;
            
            if ndims(data)>3;error('Can''t input matrix with dim>3');end
            Nblock=100;
            dim=find(size(data)~=1,1);
            if nargin>2&&~isempty(varargin{1})
                Nblock=varargin{1};
            end
            if nargin>3
                dim=varargin{2};
            end
            
            permutation=[dim,2,3];permutation(dim)=1;
            data=permute(data,permutation); %convert data so vector is dim 1
            
            data=data(1:end-mod(size(data,1),Nblock),:,:); %shorten data so N/Nblock is int
            N=size(data,1);
            NN=N/Nblock;
            f=fs*(0:NN-1)'/NN;
            win=0.5*(1-cos(2*pi*(1:NN)/NN)); %Hanning Window
            win2=meshgrid(win,1:Nblock)';
            % x1=reshape(data,NN,Nblock).*win2;
            % ftv1=sqrt(8/3)*fft(x1)/NN;
            % PSD=mean(abs(ftv1).^2/(fs/NN),2);
            
            x1=reshape(data,NN,Nblock,size(data,2),[]).*win2;
            ftv1=sqrt(8/3)*fft(x1,[],1)/NN;
            PSD=squeeze(mean(abs(ftv1).^2/(fs/NN),2));
            PSD=permute(PSD,permutation); %convert data back to original form
        end
        
        function sdisp(app,s)
            app.ProgramStatusTextArea.Value{end+1} = ['[',datestr(clock,13),'] ',...
                s];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cnt=1;
            app.ProgramStatusTextArea.Value = ['[',datestr(clock,13),']',...
                ' GUI created successfully'];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            set(app.RunButton,'Enable','off')
            
            Input_Cards = {'Dev2'};
            channels = eval(['{',app.ChannelsEditField.Value,'}']);
            Input_Channels = [uint16(length(channels))];
            
            Sample_Rate = app.SamplingHzEditField.Value;
            Sample_Duration = app.NoSamplesEditField.Value/Sample_Rate;

            
            warning('off','nidaq:ni:propUpdatedOnAllChannels');
            devices = daq.getDevices;
            device = daq.createSession('ni');
            for aa = 1:length(Input_Cards)
                addAnalogInputChannel(device,Input_Cards{aa},0:Input_Channels(aa)-1,'Bridge');
            end
            for bb = 1:sum(Input_Channels)
                % Kulites
                device.Channels(1,bb).BridgeMode = 'Full';
                device.Channels(1,bb).NominalBridgeResistance = 1000;
                device.Channels(1,bb).ExcitationVoltage = 10;
            end
            
            device.Rate = Sample_Rate;
            device.DurationInSeconds = Sample_Duration;
            device.IsContinuous = 0;
            
            sdisp(app,sprintf('Starting Data Collection %d',app.cnt));
            
            app.acq.timeseries = device.startForeground();
            
            app.acq.mean(app.cnt,:)=mean(app.acq.timeseries);
            app.acq.std(app.cnt,:)=std(app.acq.timeseries);
            
            plotData(app)
            plotDataHistory(app)
            app.cnt=app.cnt+1;
            
            set(app.DeleteAllButton,'Enable','on')
            set(app.DeleteButton,'Enable','on')
            set(app.SaveButton,'Enable','on')
            set(app.DeleteAllButton_2,'Enable','on')
            set(app.SaveButton_2,'Enable','on')
            set(app.RunButton,'Enable','on')
        end

        % Value changed function: NoSamplesEditField
        function NoSamplesEditFieldValueChanged(app, event)
            value = app.NoSamplesEditField.Value;
            app.SampleDurationsEditField.Value = app.SamplingHzEditField.Value/value;
        end

        % Value changed function: SampleDurationsEditField
        function SampleDurationsEditFieldValueChanged(app, event)
            value = app.SampleDurationsEditField.Value;
            app.NoSamplesEditField.Value = value*app.SamplingHzEditField.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            plotData(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            plotDataHistory(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            %make cell array of data for list dialog
            for ai  = 1:size(app.acq.mean,1)
            	listcell{ai}  = sprintf('index=%d, mean=%02.3f, std=%02.3f',ai,app.acq.mean(ai),app.acq.std(ai));
            end
            
            %get screensize for list dialog
            ss  = get(0,'ScreenSize');
            listx  = round(ss(3)*.25);
            listy  = round(ss(4)*.5);
            
            %make list dialog box
            sdisp(app,'Prompting user to select data for removal.');
            selection  = listdlg('liststring',listcell,'selectionmode','multiple','name','Delete', ...
            	'PromptString','Select data to remove:','okstring','Delete','listsize',[listx,listy]);
            if ~isempty(selection)
            	sdisp(app,sprintf('%i data points selected for removal.',length(selection)));
            	%prompt user again to make sure
                choice2  = questdlg('Are you REALLY sure?  The data will be lost FOREVER.','Warning','Yes','No','No');
                if isequal(choice2,'Yes')
            		if isequal(length(selection),length(app.acq.mean))
            			%clear all data
            			app.acq.mean  = [];
            	 		app.acq.std   = []; 	
                        app.cnt = 1;
            			sdisp(app,sprintf('Deleted all %i data points.',length(selection)));
            		else
            	    	%clear the selected data
            	        app.acq.mean(selection,:)  = [];
            	        app.acq.std(selection,:)   = [];
                        app.cnt = max(app.cnt-length(selection),1);
            	        sdisp(app,sprintf('Deleted %i data points.',length(selection)));			
            		end
                    plotDataHistory(app)
                    
            	else
            		sdisp(app,'Data removal cancelled.');
                end
            else
            	sdisp(app,'Data removal cancelled.');
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            choice1 = questdlg('Are you sure you want to clear all stored mean and standard deviation data?','Warning','Yes','No','No');
            if strcmp(choice1,'Yes')
               choice2 = questdlg('Are you sure REALLY sure? The data will be lost FOREVER','Warning','Yes','No','No');
               if strcmp(choice2,'Yes')
                   app.acq.mean=[];
                   app.acq.std=[];
                   app.cnt=1;
                   plot(app.UIAxes2,0,nan, ".-")
                   sdisp(app,'Data History cleared')
                
                    set(app.DeleteAllButton,'Enable','off')
                    set(app.DeleteButton,'Enable','off')
                    set(app.SaveButton,'Enable','off')
               end
            end
       
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Prompt user for fie
            [file,path] = uiputfile(...
                {'*.mat','MAT-files (*.mat)'},...
                'Select a file location');
            % check that a file and directory were selected
            if ~sum(file) || ~sum(path)
                return
            end
            
            fs = app.SamplingHzEditField.Value;
            channels = eval(['[',app.ChannelsEditField.Value,']']);
            npts = app.NoSamplesEditField.Value;
            mean_data = app.acq.mean;
            std_data = app.acq.std;
            
            save([path,file],'fs','channels','npts','mean_data','std_data')
            sdisp(app,['Data History saved to',file]);
        end

        % Button pushed function: DeleteAllButton_2
        function DeleteAllButton_2Pushed(app, event)
            choice1 = questdlg('Are you sure you want to clear the last run''s timeseries data?','Warning','Yes','No','No');
            if strcmp(choice1,'Yes')
                app.acq.timeseries=[];
                app.cnt=max(app.cnt-1,1);
                plot(app.UIAxes,0,nan, ".-")
                sdisp('Data Timeseries cleared');
                set(app.DeleteAllButton_2,'Enable','off')
                set(app.SaveButton_2,'Enable','off')
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            % Prompt user for fie
            [file,path] = uiputfile(...
                {'*.mat','MAT-files (*.mat)'},...
                'Select a file location');
            % check that a file and directory were selected
            if ~sum(file) || ~sum(path)
                return
            end
            
            fs = app.SamplingHzEditField.Value;
            channels = eval(['[',app.ChannelsEditField.Value,']']);
            npts = app.NoSamplesEditField.Value;
            timeseries_data = app.acq.timeseries;
            
            save([path,file],'fs','channels','npts','timeseries_data')
            sdisp(app,['Data History saved to',file]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'voltage')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'test')
            ylabel(app.UIAxes2, 'voltage')
            app.ProgramStatusTextAreaLabel = uilabel(app.UIFigure);
            app.ProgramStatusTextAreaLabel.Text = 'Program Status';
            app.ProgramStatusTextArea = uitextarea(app.UIFigure);
            app.ProgramStatusTextArea.Editable = 'off';
            app.AnalogInPanel = uipanel(app.UIFigure);
            app.AnalogInPanel.Title = 'Analog In';
            app.SamplingHzEditFieldLabel = uilabel(app.AnalogInPanel);
            app.SamplingHzEditFieldLabel.Text = 'Sampling [Hz]';
            app.SamplingHzEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.SamplingHzEditField.Value = 10000;
            app.NoSamplesEditFieldLabel = uilabel(app.AnalogInPanel);
            app.NoSamplesEditFieldLabel.Text = 'No. Samples';
            app.NoSamplesEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.NoSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @NoSamplesEditFieldValueChanged, true);
            app.NoSamplesEditField.Value = 100000;
            app.SampleDurationsEditFieldLabel = uilabel(app.AnalogInPanel);
            app.SampleDurationsEditFieldLabel.Text = 'Sample Duration [s]';
            app.SampleDurationsEditField = uieditfield(app.AnalogInPanel, 'numeric');
            app.SampleDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDurationsEditFieldValueChanged, true);
            app.SampleDurationsEditField.Value = 10;
            app.ChannelsEditFieldLabel = uilabel(app.AnalogInPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.AnalogInPanel, 'text');
            app.ChannelsEditField.Value = '00,01';
            app.ExecutePanel = uipanel(app.UIFigure);
            app.ExecutePanel.Title = 'Execute';
            app.RunButton = uibutton(app.ExecutePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.SaveButton_2 = uibutton(app.DataPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.DropDown_2 = uidropdown(app.DataPanel);
            app.DropDown_2.Items = {'Time-series', 'FFT', 'FFT loglog'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Time-series';
            app.DeleteAllButton_2 = uibutton(app.DataPanel, 'push');
            app.DeleteAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButton_2Pushed, true);
            app.DeleteAllButton_2.Enable = 'off';
            app.DeleteAllButton_2.Text = 'Delete All';
            app.DataHistoryPanel = uipanel(app.UIFigure);
            app.DataHistoryPanel.Title = 'Data History';
            app.DropDown = uidropdown(app.DataHistoryPanel);
            app.DropDown.Items = {'Mean', 'Standard Deviation', 'Mean with STD Bars', 'Mean minus first point'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Mean';
            app.DeleteButton = uibutton(app.DataHistoryPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.DeleteAllButton = uibutton(app.DataHistoryPanel, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Enable = 'off';
            app.DeleteAllButton.Text = 'Delete All';
            app.SaveButton = uibutton(app.DataHistoryPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end